classdef mainControllerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UIAxes                matlab.ui.control.UIAxes
        AmplitudeSliderLabel  matlab.ui.control.Label
        AmplitudeSlider       matlab.ui.control.Slider
        OpenButton            matlab.ui.control.Button
        Label                 matlab.ui.control.Label
        PortList              matlab.ui.control.DropDown
    end


    properties (Access = private)
        myTimer % Description
        s % Serial port
        ElDesiredAngle % Input El angle
        AzDesiredAngle % Input Az angle
        ElCurrentAngle % El angle in GS
        AzCurrentAngle % Az angle in GS
        time            % time array
        t               % timer (for update GUI and send data)
        time_step = 0.1; % fixed time step of calling SendData function
    end

    methods (Access = private)
    
        function sendElDesiredAngle(app)
             if app.s.BytesToOutput == 0
                current_values = 0.5 * current_values; % decrease maximum brightness to prevent your eyes burning
                values_to_send = uint8(2.55 * (100 - current_values)); % scale color values to 255..0 uint8 range
                fwrite(app.s, values_to_send, 'uint8', 'async'); % send prepared color values over serial to Arduino
            end
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
             % set up timer
            app.t = timer;
            app.t.TimerFcn = @(~, ~)app.SendData;
            app.t.ExecutionMode = 'fixedRate';
            app.t.Period = app.time_step;
            app.t.BusyMode = 'drop';
            
            % looking for available serial ports 
            % (Instrument Control Toolbox required)
            p = instrhwinfo('serial');
            app.PortList.Items = p.AvailableSerialPorts;
        end

        % Value changed function: AmplitudeSlider
        function AmplitudeSliderValueChanged(app, event)
            value = app.AmplitudeSlider.Value;
            plot(app.UIAxes, value*peaks)
            app.UIAxes.YLim = [-1000 1000];
            
            
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            %serialPort = serial(app.TextArea,'BAUD',9600);
            %fopen(serialPort);
            
            %{ 
            if strcmp(app.t.running, 'off')
                app.s = serial(app.PortList.Value);
                % call ReadData function if buffer contains uint16 (2 bytes)
                app.s.BytesAvailableFcnCount = 2;
                app.s.BytesAvailableFcnMode = 'byte';
                app.s.BytesAvailableFcn = @(~, ~)app.ReadData;
                fopen(app.s);
                start(app.t);
                app.Lamp.Color = app.GREEN_COLOR;
            end 
            %}
            if strcmp(app.t.running, 'off')
                app.s = serial(app.PortList.Value);
                
            end
        
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %delete(app)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.AmplitudeSliderLabel = uilabel(app.UIFigure);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.AmplitudeSlider = uislider(app.UIFigure);
            app.AmplitudeSlider.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSliderValueChanged, true);
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.PortList = uidropdown(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = mainControllerApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end